classdef CenteredNetworsAnalysisPLOTS_new < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        SelectxlsFileButton         matlab.ui.control.Button
        EditFieldxlsFile            matlab.ui.control.EditField
        SelectFolderButton          matlab.ui.control.Button
        EditFieldFolder             matlab.ui.control.EditField
        StartButton                 matlab.ui.control.Button
        PlotOptionsPanel            matlab.ui.container.Panel
        OneConditionCheckBox        matlab.ui.control.CheckBox
        ConditionVsControlCheckBox  matlab.ui.control.CheckBox
        AverageCheckBox             matlab.ui.control.CheckBox
        XlsFileCheckLabel           matlab.ui.control.Label
        FolderCheckLabel            matlab.ui.control.Label
        CondGridLayout              matlab.ui.container.GridLayout
        PlusButton                  matlab.ui.control.Button
        ConditiondescriptionLabel   matlab.ui.control.Label
        ExperimenttypeLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        cond % conditions ui elements
        ExpTypes % cell array of experiment indices from xls file
        ExpTexts % cell array of experiment descriptions from xls file
        CheckFolder = false; % output folder test
        CheckXLS = false; % xls file test
    end
    
    methods (Access = private)
        
        % Create ui elements for another condition
        function addCond(app)
            rowHeight = 30; % Element height;
            g = app.CondGridLayout;
            condNum = length(app.cond) + 1; % Number of the new condition
            rowNum = condNum + 1; % Row in the grid. First row is titles, so +1
            g.RowHeight = num2cell(ones(1,rowNum) * rowHeight); % Add row to grid
            
            % Create condition label
            condLabel = uilabel(g);
            condLabel.Layout.Row = rowNum;
            condLabel.Layout.Column = 1;
            condLabel.Text = sprintf('Condition %d', condNum);
            
            % Create condition dropdown menu
            condIndex = uidropdown(g);
            condIndex.Layout.Row = rowNum;
            condIndex.Layout.Column = 2;
            condIndex.Editable = true;
            condIndex.ValueChangedFcn = createCallbackFcn(app,@dropDownChanged,true);
            condIndex.Tooltip = sprintf('Select experiment type from the list, or type its excel-file index.\nTo include more than one index in a condition type the indices in matlab notation using commas and colons, such as "3010951, 3010952, 3010953" or "3010951:3010953".');
            
            % Create condition description field
            condDesc = uieditfield(g);
            condDesc.Layout.Row = rowNum;
            condDesc.Layout.Column = 3;
            condDesc.Tooltip = 'This field updates with the condition description from the excel file, but can be changed manually.';
            
            % Create condition remove button
            minusButton = uibutton(g);
            minusButton.Layout.Row = rowNum;
            minusButton.Layout.Column = 4;
            minusButton.Text = '-';
            minusButton.FontSize = 20;
            minusButton.FontWeight = 'bold';
            minusButton.ButtonPushedFcn = createCallbackFcn(app,@removeCond,true);
            minusButton.Tooltip = 'Remove condition';
            
            % Add ui elements to cond property for easy access
            app.cond(condNum).name = condDesc;
            app.cond(condNum).index = condIndex;
            app.cond(condNum).label = condLabel;
            app.cond(condNum).minusButton = minusButton;
            
            % Populate dropdown menu
            updateExpDropdownMenus(app, condNum);
            
            % Increse windwow size
            dh = g.RowSpacing + rowHeight;
            app.UIFigure.Position(4) = app.UIFigure.Position(4) + dh;
            app.UIFigure.Position(2) = app.UIFigure.Position(2) - dh;
            
            % Disable remove button for first condition if it is the only
            % one, enable otherwise
            if length(app.cond) == 1
                app.cond(1).minusButton.Enable = false;
            else
                app.cond(1).minusButton.Enable = true;
            end
            
        end
        
        % Remove ui elements of condition
        function removeCond(app, event)
            rowHeight = 30; % Element height;
            g = app.CondGridLayout;
            condNum = event.Source.Layout.Row - 1; % Get condition number to remove from row number in grid. Fist row is titles so -1.
            
            if (length(app.cond) <= 1) % Don't remove last condition
                return;
            end
            
            for i = (condNum):length(app.cond)
                for f=fieldnames(app.cond(i))' % Move following conditions one row up
                    app.cond(i).(f{:}).Layout.Row = i;
                end
                app.cond(i).label.Text = sprintf('Condition %d', i-1); % Update following conditions row number
            end
            delete(struct2array(app.cond(condNum))); % Delete ui elemnts
            app.cond(condNum) = []; % Delete from cond property
            
            % Decrease window size
            dh = g.RowSpacing + rowHeight;
            g.RowHeight = num2cell(ones(1,length(app.cond) + 1) * rowHeight);
            app.UIFigure.Position(4) = app.UIFigure.Position(4) - dh;
            app.UIFigure.Position(2) = app.UIFigure.Position(2) + dh;
            
            % Disable remove button for first condition if it is the only one
            if length(app.cond) == 1
                app.cond(1).minusButton.Enable = false;
            end
        end
        
        % Load experiment indices and descriptions from xls file
        function loadExpsFromXls(app)
            xlsFile = app.EditFieldxlsFile.Value;
            if ~isfile(xlsFile) % Check file existance
                fprintf('Excel file not found\n');
                toggleCheckXLS(app, false); % file does not exist
                return;
            end
            T = readtable(xlsFile, 'Range','A:BF');
            varNames = T.Properties.VariableNames;
            if sum(ismember({'ExperimentType', 'TypeOfExperiment_text_'}, varNames)) ~= 2 % Check if fields exist in xls file
                fprintf('could not find ExperimentType and TypeOfExperiment_text_ columns in excel file table\n')
                toggleCheckXLS(app, false); % Fields do not exist in xls file
                return;
            end
            
            expTypes = T.ExperimentType;
            expTexts = T.TypeOfExperiment_text_;
            removeInd = isnan(expTypes);
            expTypes(removeInd) = [];
            expTexts(removeInd) = [];
            [uniqueExpTypes,ia,~] = unique(expTypes);
            uniqueExpTexts = expTexts(ia);
            [uniqueExpTexts, Ind] = sort(uniqueExpTexts);
            uniqueExpTypes = uniqueExpTypes(Ind);
            
            app.ExpTexts = uniqueExpTexts;
            app.ExpTypes = num2cell(uniqueExpTypes);
            toggleCheckXLS(app, true); % Mark file OK
        end
        
        % Mark xls file OK or not according to input check. If not, empty ExpTypes and ExpText
        function toggleCheckXLS(app, check)
            if check
                app.XlsFileCheckLabel.Text = '✓';
                app.XlsFileCheckLabel.FontColor = 'green';
                app.CheckXLS = true;
                if(app.CheckFolder)
                    app.StartButton.Enable = true;
                end
            else
                app.XlsFileCheckLabel.Text = '✕';
                app.XlsFileCheckLabel.FontColor = 'red';
                app.ExpTypes = {};
                app.ExpTexts = {};
                app.CheckXLS = false;
                app.StartButton.Enable = false;
            end
        end
        
        % Populate dropdown menus of all or specified conditions
        function updateExpDropdownMenus(app, condNums)
            if ~exist('condNums', 'var') || isempty(condNums)
                condNums = 1:length(app.cond); % If no conditions specified, do all conditions
            end
            % Populate with 'condition description: condition index'
            ddText = cellfun(@(type, text) sprintf('%s: %d', type, text), app.ExpTexts, app.ExpTypes, 'UniformOutput', false);
            
            items = ['Select Condition', ddText(:)']; % Add first item to list - 'Select condition', than the rest of the texts
            itemsData = [nan, app.ExpTypes(:)']; % Add first item to data list - nan (for 'Select condition'), than the rest of the data
            
            %Populate dropdown menus with texts and data lists
            for i=condNums
                app.cond(i).index.Items = items;
                app.cond(i).index.ItemsData = itemsData;
            end
        end
        
        % Callback for dropdown menus to update the condition description
        function dropDownChanged(app, event)
            condNum = event.Source.Layout.Row - 1; % Get condition number of changed dropdown
            if isempty(event.Value) || ~isnumeric(event.Value) % TODO - perhaps change excel index to allow non numeric types?
                expInd = [];
            else
                expInd = find(cell2mat(app.ExpTypes) == event.Value,1); % Find experiment index in list
            end
            
            if ~isempty(expInd)
                condName = app.ExpTexts{expInd};
                app.cond(condNum).name.Value = condName;
            end
        end
        
        % Check if output folder exists and mark accordingly
        function checkFolder(app)
            if isfolder(app.EditFieldFolder.Value)
                app.FolderCheckLabel.Text = '✓';
                app.FolderCheckLabel.FontColor = 'green';
                app.CheckFolder = true;
                if app.CheckXLS
                    app.StartButton.Enable = true;
                end
            else
                app.FolderCheckLabel.Text = '✕';
                app.FolderCheckLabel.FontColor = 'red';
                app.CheckFolder = false;
                app.StartButton.Enable = false;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            loadExpsFromXls(app); % Load experiment list form xls file
            addCond(app); % Add first condition to ui
            checkFolder(app); % chack output folder
        end

        % Button pushed function: SelectxlsFileButton
        function SelectxlsFileButtonPushed(app, event)
            [xls_file]=uigetfile('*.xlsx'); % get xls file from user
            figure(app.UIFigure); % refocus ui window (workaround for Matlab<2020 bug)
            if ~isempty(xls_file) && ischar(xls_file)
                app.EditFieldxlsFile.Value = xls_file;
                loadExpsFromXls(app); % Load experiment list form xls file
                updateExpDropdownMenus(app);% Update dropdown menus of all conditions with experiment list
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            curFolder = app.EditFieldFolder.Value;
            if (isempty(curFolder) || strcmp(curFolder,''))
                curFolder = fullfile(getenv('USERPROFILE'), 'Documents\plots'); % Root of ouput folders, change if needed
            end
            [SaveToFolder]=uigetdir(curFolder);
            figure(app.UIFigure);
            if ~isempty(SaveToFolder) && ischar(SaveToFolder)
                app.EditFieldFolder.Value = SaveToFolder;
                checkFolder(app);
            end
        end

        % Button pushed function: PlusButton
        function PlusButtonPushed(app, event)
            addCond(app);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if ~app.CheckFolder
                fprintf('Can not open output folder. Plots aborted.\n');
                return
            end
            if ~app.CheckXLS
                fprintf('Can not open xls file, or file does not contain necessary fields. Plots aborted.\n');
                return
            end
            
            % Collect experiment indices and description from ui elements
            expTypes = cell(1,length(app.cond));
            expTypeStrings = cell(1,length(app.cond));
            for i=1:length(app.cond)
                expTypes{i} = app.cond(i).index.Value;
                if ~isnumeric(expTypes{i})
                    expTypes{i} = str2num(expTypes{i}); % str2num can convert matlab notation in strings to apropriate numbers
                end
                expTypeStrings{i} = app.cond(i).name.Value;
            end
            
            nanInd = find(isnan([expTypes{:}]));
            expTypes(nanInd) = [];
            expTypeStrings(nanInd) = [];
            
            % Collect rest of the data from ui
            plotFlags(1) = app.OneConditionCheckBox.Value;
            plotFlags(2) = app.ConditionVsControlCheckBox.Value;
            plotFlags(3) = app.AverageCheckBox.Value;
            xlsFile = app.EditFieldxlsFile.Value;
            saveToFolder=app.EditFieldFolder.Value;
            
            % Run plotting functions
            doPlots(expTypes, expTypeStrings, xlsFile, saveToFolder, plotFlags);
        end

        % Value changed function: EditFieldxlsFile
        function EditFieldxlsFileValueChanged(app, event)
            loadExpsFromXls(app); % Load experiment list form xls file
            updateExpDropdownMenus(app);% Update dropdown menus of all conditions with experiment list
        end

        % Value changed function: EditFieldFolder
        function EditFieldFolderValueChanged(app, event)
            checkFolder(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectxlsFileButton = uibutton(app.GridLayout, 'push');
            app.SelectxlsFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectxlsFileButtonPushed, true);
            app.SelectxlsFileButton.Text = 'Select xls File';
            app.EditFieldxlsFile = uieditfield(app.GridLayout, 'text');
            app.EditFieldxlsFile.ValueChangedFcn = createCallbackFcn(app, @EditFieldxlsFileValueChanged, true);
            app.EditFieldxlsFile.Editable = 'off';
            app.EditFieldxlsFile.Value = '3D networks data.xlsx';
            app.SelectFolderButton = uibutton(app.GridLayout, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder for saving the figures';
            app.EditFieldFolder = uieditfield(app.GridLayout, 'text');
            app.EditFieldFolder.ValueChangedFcn = createCallbackFcn(app, @EditFieldFolderValueChanged, true);
            app.StartButton = uibutton(app.GridLayout, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.PlotOptionsPanel = uipanel(app.GridLayout);
            app.PlotOptionsPanel.Title = 'Choose plotting option';
            app.OneConditionCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.OneConditionCheckBox.Text = 'Actin network properties - one condition';
            app.ConditionVsControlCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.ConditionVsControlCheckBox.Text = 'Actin network properties - condition vs control';
            app.AverageCheckBox = uicheckbox(app.PlotOptionsPanel);
            app.AverageCheckBox.Text = 'Actin network properties - average';
            app.XlsFileCheckLabel = uilabel(app.GridLayout);
            app.XlsFileCheckLabel.Text = 'X';
            app.FolderCheckLabel = uilabel(app.GridLayout);
            app.FolderCheckLabel.Text = 'X';
            app.CondGridLayout = uigridlayout(app.GridLayout);
            app.PlusButton = uibutton(app.CondGridLayout, 'push');
            app.PlusButton.ButtonPushedFcn = createCallbackFcn(app, @PlusButtonPushed, true);
            app.PlusButton.Tooltip = {'Add condition'};
            app.PlusButton.Text = '+';
            app.ConditiondescriptionLabel = uilabel(app.CondGridLayout);
            app.ConditiondescriptionLabel.Text = 'Condition description';
            app.ExperimenttypeLabel = uilabel(app.CondGridLayout);
            app.ExperimenttypeLabel.Tooltip = {''};
            app.ExperimenttypeLabel.Text = 'Experiment type';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CenteredNetworsAnalysisPLOTS_new

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end